classdef IF4073_Plate_Detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ImagesPanel               matlab.ui.container.Panel
        BrowseforWatermarkButton  matlab.ui.control.Button
        ExecuteButton             matlab.ui.control.Button
        BrowseforImageButton      matlab.ui.control.Button
        ImageSrc                  matlab.ui.control.Image
        ImageGray                 matlab.ui.control.Image
        ImageCrop                 matlab.ui.control.Image
        WatermarkLabel            matlab.ui.control.Label
        EmbeddedLabel             matlab.ui.control.Label
        SourceLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        OriginalImg
        GrayImg
        CroppedImg
    end
    methods (Access = private)
        function Execute(app, I, W)
            app.OriginalImg = I;
            app.GrayImg = imbinarize(W(:, :, 3));
            %imshow(app.OriginalImg);
            %imshow(app.GrayImg);
            res = zeros(size(I));
            for col = 1:size(I, 3)
                for r = 1:size(I, 1)
                    for c = 1:size(I, 2)
                        if r < size(W, 1) && c < size(W, 2)
                            res(r, c, col) = bitand(I(r,c,col), 0xFE) + bitand(uint8(app.GrayImg(r,c)), 1);
                        else
                            res(r, c, col) = bitand(I(r,c,col), 0xFE);
                        end
                    end
                end
            end
            app.ImageCrop.ImageSource = uint8(res);
            
            b = bitand(uint8(res), 0x1);
            for col = 1:size(b, 3)
                for r = 1:size(b, 1)
                    for c = 1:size(b, 2)
                        if b(r, c, col) > 0
                            b(r, c, col) = 256;
                        end
                    end
                end
            end
            figure, imshow(uint8(b));
            
            imwrite(uint8(res), "test.jpg")
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: BrowseforImageButton
        function BrowseforImageButtonPushed(app, event)
            [file,path] = uigetfile({'*.png;*.jpg;*.jpeg','Images'});
            if (file ~= 0)
                app.OriginalImg = imread(fullfile(path,file));
                app.ImageSrc.ImageSource = app.OriginalImg;
            end
        end

        % Callback function: not associated with a component
        function PlateTemplateDropDownValueChanged(app, event)

        end

        % Callback function: not associated with a component
        function RotateImageCheckBoxValueChanged(app, event)
            
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            app.Execute(app.ImageSrc.ImageSource, app.ImageGray.ImageSource);
        end

        % Button pushed function: BrowseforWatermarkButton
        function BrowseforWatermarkButtonPushed(app, event)
            [file,path] = uigetfile({'*.png;*.jpg;*.jpeg','Images'});
            if (file ~= 0)
                app.GrayImg = imread(fullfile(path,file));
                app.ImageGray.ImageSource = app.GrayImg;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImagesPanel = uipanel(app.UIFigure);
            app.ImagesPanel.Title = 'Images';
            app.SourceLabel = uilabel(app.ImagesPanel);
            app.SourceLabel.Text = 'Source';
            app.EmbeddedLabel = uilabel(app.ImagesPanel);
            app.EmbeddedLabel.Text = 'Embedded';
            app.WatermarkLabel = uilabel(app.ImagesPanel);
            app.WatermarkLabel.Text = 'Watermark';
            app.ImageCrop = uiimage(app.ImagesPanel);
            app.ImageGray = uiimage(app.ImagesPanel);
            app.ImageSrc = uiimage(app.ImagesPanel);
            app.BrowseforImageButton = uibutton(app.ImagesPanel, 'push');
            app.BrowseforImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseforImageButtonPushed, true);
            app.BrowseforImageButton.Text = 'Browse for Image ...';
            app.ExecuteButton = uibutton(app.ImagesPanel, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.BrowseforWatermarkButton = uibutton(app.ImagesPanel, 'push');
            app.BrowseforWatermarkButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseforWatermarkButtonPushed, true);
            app.BrowseforWatermarkButton.Text = 'Browse for Watermark ...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IF4073_Plate_Detection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end